classdef ImageFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SamuelAdkinsCOT5930MATLABAssig1Z23557914Label  matlab.ui.control.Label
        SimpleImageFilteringAppLabel  matlab.ui.control.Label
        SaveOutputImageButton         matlab.ui.control.Button
        SelectFilterDropDown          matlab.ui.control.DropDown
        SelectFitlerDropDownLabel     matlab.ui.control.Label
        SelectImageDropDown           matlab.ui.control.DropDown
        SelectImageDropDownLabel      matlab.ui.control.Label
        OutputImageAxes               matlab.ui.control.UIAxes
        InputImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImg % Property stores original image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initial Display
            app.OriginalImg = imread('peppers.png'); % default image is peppers
            % originalImg = imshow(app.OriginalImg, 'Parent' , app.InputImageAxes);
            % set(originalImg,'tag','inputImage');
            %title("Input Image");
            imshow(app.OriginalImg, 'Parent', app.InputImageAxes);
            % Display unfiltered default img in Output at startup
            imshow(app.OriginalImg, 'Parent', app.OutputImageAxes);
        end

        % Drop down opening function: SelectImageDropDown
        function SelectImageDropDownOpening(app, event)
            % List of MATLAB images
            imgList = {'peppers.png', 'coins.png', 'pears.png', 'bag.png', 'forest.tif'};
        
            % Set the list of filters to the dropdown
            app.SelectImageDropDown.Items = imgList;
        end

        % Value changed function: SelectImageDropDown
        function SelectImageDropDownValueChanged(app, event)
            selectedImg = app.SelectImageDropDown.Value;
            app.OriginalImg = imread(selectedImg);
            imshow(app.OriginalImg, 'Parent', app.InputImageAxes);
        end

        % Drop down opening function: SelectFilterDropDown
        function SelectFilterDropDownOpening(app, event)
            % List of filters
            filterList = {'No Filter', 'Box', 'Gaussian', 'Median', 'Edge Detection (Sobel)', 'Edge Detection (Canny)', 'Edge Detection (Prewitt)', 'Noise Removal', 'Background Isolation'};
        
            % Set the list of filters to the dropdown
            app.SelectFilterDropDown.Items = filterList;
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)
            selectedFilter = app.SelectFilterDropDown.Value;
            % Check which filter is selected and apply it
            switch selectedFilter

                case 'No Filter'
                    filteredImage = app.OriginalImg;
                    
                case 'Box'
                    % Apply box filter of Size 1
                    filterSize = 1;
                    filteredImage = imboxfilt(im2gray(app.OriginalImg), filterSize);
                    
                case 'Gaussian'
                    % Example of applying Gaussian blur
                    filterSigma = 2;
                    filteredImage = imgaussfilt(im2gray(app.OriginalImg),filterSigma);

                case 'Median'
                    % Apply median filter for each pixel with med. val in
                    % m-n neighborhood
                    filteredImage = medfilt2(im2gray(app.OriginalImg), [3 3]);
                 
                case 'Edge Detection (Sobel)'
                    % Apply Sobel filter for edge detection
                    filteredImage = edge(im2gray(app.OriginalImg), 'sobel');

                case 'Edge Detection (Canny)'
                    % Apply Edge Detection using Canny method
                    filteredImage = edge(im2gray(app.OriginalImg), 'Canny');

                case 'Edge Detection (Prewitt)'
                    % Apply Edge Detection using Canny method
                    filteredImage = edge(im2gray(app.OriginalImg), 'Prewitt');

                case 'Noise Removal'
                    % Remove noise - filter size 5-by-5
                    filteredImage = wiener2(im2gray(app.OriginalImg), [5 5]);
        
                case 'Background Isolation'
                    % Convert to grayscale and threshold the image to create a mask
                    grayImage = im2gray(app.OriginalImg);
                    % Isolate the backgCround
                    background = imopen(grayImage, strel('disk', 15));
                    % Subtract the background
                    filteredImage = imsubtract(grayImage, background);
                
                otherwise
                    % If no filter is selected or recognized, display the original image
                    filteredImage = app.OriginalImg;
            end
        
            % Display the filtered image
            imshow(filteredImage, 'Parent', app.OutputImageAxes);
        end

        % Button pushed function: SaveOutputImageButton
        function SaveOutputImageButtonPushed(app, event)
            exportgraphics(app.OutputImageAxes, 'filteredImage.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImageAxes = uiaxes(app.UIFigure);
            title(app.InputImageAxes, 'Input Image')
            app.InputImageAxes.XTick = [];
            app.InputImageAxes.XTickLabel = '';
            app.InputImageAxes.YTick = [];
            app.InputImageAxes.YTickLabel = '';
            app.InputImageAxes.ZTick = [];
            app.InputImageAxes.Tag = 'inputImage';
            app.OutputImageAxes = uiaxes(app.UIFigure);
            title(app.OutputImageAxes, 'Output Image')
            app.OutputImageAxes.XTick = [];
            app.OutputImageAxes.YTick = [];
            app.OutputImageAxes.ZTick = [];
            app.OutputImageAxes.Tag = 'outputImage';
            app.SelectImageDropDownLabel = uilabel(app.UIFigure);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.UIFigure);
            app.SelectImageDropDown.Items = {'peppers.png'};
            app.SelectImageDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectImageDropDownOpening, true);
            app.SelectImageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectImageDropDownValueChanged, true);
            app.SelectImageDropDown.Placeholder = 'peppers.png';
            app.SelectImageDropDown.Value = 'peppers.png';
            app.SelectFitlerDropDownLabel = uilabel(app.UIFigure);
            app.SelectFitlerDropDownLabel.Text = 'Select Fitler';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'No Filter'};
            app.SelectFilterDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectFilterDropDownOpening, true);
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = 'No Filter';
            app.SaveOutputImageButton = uibutton(app.UIFigure, 'push');
            app.SaveOutputImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOutputImageButtonPushed, true);
            app.SaveOutputImageButton.Tag = 'saveButton';
            app.SaveOutputImageButton.Text = 'Save Output Image';
            app.SimpleImageFilteringAppLabel = uilabel(app.UIFigure);
            app.SimpleImageFilteringAppLabel.Text = 'Simple Image Filtering App';
            app.SamuelAdkinsCOT5930MATLABAssig1Z23557914Label = uilabel(app.UIFigure);
            app.SamuelAdkinsCOT5930MATLABAssig1Z23557914Label.Text = {'Samuel Adkins'; 'COT 5930'; 'MATLAB Assig. 1'; 'Z23557914'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end